classdef AppleJudge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Label       matlab.ui.control.Label
        AppleJudgesAppforappleidentificationintreesLabel  matlab.ui.control.Label
        Image       matlab.ui.control.Image
        LoadButton  matlab.ui.control.Button
        ImageAxes   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                imgOri = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end            

            image(app.ImageAxes,imgOri);
            
            r = imgOri(:,:,1);
            g = imgOri(:,:,2);
            b = imgOri(:,:,3);
            
            Drg = uint8(uint16(r-g)*256./uint16(r+g+b));
            
            Drg_bin = imbinarize(Drg,100/256);
            
            se = strel('diamond',3);
            imgClean = imopen(Drg_bin,se);
            imgClean = imclose(imgClean,se);
            
            caract = regionprops(imgClean, 'all');

            for i = 1:length(caract)
                rectangle(app.ImageAxes, 'Position', caract(i).BoundingBox, 'LineWidth', 2, 'EdgeColor', 'r')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and do things
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Apple Judge';
            app.UIFigure.Icon = 'AJ_Logo.png';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'AJ_Logo.png';
            app.AppleJudgesAppforappleidentificationintreesLabel = uilabel(app.UIFigure);
            app.AppleJudgesAppforappleidentificationintreesLabel.Text = 'Apple Judge''s App for apple identification in trees';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Authors:'; 'Fernanda Suarez'; 'Pablo García'; 'Ruth Castañeda'; 'Pablo Daniel Martín'; 'Dylan Quintero'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppleJudge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end